classdef FoSI_sync < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ImageTimeSyncTab            matlab.ui.container.Tab
        CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel  matlab.ui.control.Label
        CTDTIMEwihcalibrationclockEditFieldLabel  matlab.ui.control.Label
        CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel  matlab.ui.control.Label
        CTDDateEditFieldLabel       matlab.ui.control.Label
        ImageFormatLabel            matlab.ui.control.Label
        ImageInputDirectoryLabel    matlab.ui.control.Label
        pathdisp                    matlab.ui.control.Label
        CALICTDhour                 matlab.ui.control.NumericEditField
        CTDhour                     matlab.ui.control.NumericEditField
        CALIcamerahour              matlab.ui.control.NumericEditField
        FoSITIMEhour                matlab.ui.control.NumericEditField
        Label_10                    matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        CALICTDsec                  matlab.ui.control.NumericEditField
        CALICTDmin                  matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        CTDsec                      matlab.ui.control.NumericEditField
        CTDmin                      matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        CALIcamerasec               matlab.ui.control.NumericEditField
        CALIcameramin               matlab.ui.control.NumericEditField
        SECLabel                    matlab.ui.control.Label
        MINLabel                    matlab.ui.control.Label
        HOURLabel                   matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        FOSItimesec                 matlab.ui.control.NumericEditField
        FOSItimemin                 matlab.ui.control.NumericEditField
        CTDdays                     matlab.ui.control.NumericEditField
        CTDmon                      matlab.ui.control.NumericEditField
        CTDyear                     matlab.ui.control.NumericEditField
        DAYSLabel                   matlab.ui.control.Label
        MONTHLabel                  matlab.ui.control.Label
        YEARLabel                   matlab.ui.control.Label
        chooseButton                matlab.ui.control.Button
        HHMMSSeg130901Label         matlab.ui.control.Label
        STARTSYNCHRONIZATIONButton  matlab.ui.control.Button
        YYMMDDeg230507Label         matlab.ui.control.Label
        ImageFormatDropDown         matlab.ui.control.DropDown
        TimeSynchronizationforFoSIimageswithCTDLabel  matlab.ui.control.Label
        ImageSubtractionTab         matlab.ui.container.Tab
        ParticleProfilesTab         matlab.ui.container.Tab
    end


    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)

            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            % if iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp.Text=inputpath;  % Sets the label text to be the selected path
            % end
        end

        % Button pushed function: STARTSYNCHRONIZATIONButton
        function STARTSYNCHRONIZATIONButtonPushed(app, event)
            h = waitbar(0,'Please wait...');
            pause(1)
            path_dir=app.pathdisp.Text;
            hh_fosi=app.FoSITIMEhour.Value;
            mm_fosi=app.FOSItimemin.Value;
            ss_fosi=app.FOSItimesec.Value;
            fosi_time=[hh_fosi,mm_fosi,ss_fosi];

            hh_calcam=app.CALIcamerahour.Value; 
            mm_calcam=app.CALIcameramin.Value; 
            ss_calcam=app.CALIcamerasec.Value; 
            calcam_time=[hh_calcam,mm_calcam,ss_calcam];

            hh_ctd=app.CTDhour.Value;
            mm_ctd=app.CTDmin.Value;
            ss_ctd=app.CTDsec.Value;
            ctd_time=[hh_ctd,mm_ctd,ss_ctd];

            hh_calctd=app.CALICTDhour.Value;
            mm_calctd=app.CALICTDmin.Value;
            ss_calctd=app.CALICTDsec.Value;
            calctd_time=[hh_calctd,mm_calctd,ss_calctd];

            im_format=app.ImageFormatDropDown.Value;
            datei=[app.CTDyear.Value,app.CTDmon.Value,app.CTDdays.Value];
         
            fositimesynchronizer(path_dir,fosi_time,calcam_time,ctd_time,calctd_time,datei,im_format,h) 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTimeSyncTab = uitab(app.TabGroup);
            app.ImageTimeSyncTab.Title = 'Image Time Sync';
            app.TimeSynchronizationforFoSIimageswithCTDLabel = uilabel(app.ImageTimeSyncTab);
            app.TimeSynchronizationforFoSIimageswithCTDLabel.Text = 'Time Synchronization for FoSI images with CTD';
            app.ImageFormatDropDown = uidropdown(app.ImageTimeSyncTab);
            app.ImageFormatDropDown.Items = {'.bmp', '.tiff', '.jpg', '.png'};
            app.ImageFormatDropDown.Value = '.bmp';
            app.YYMMDDeg230507Label = uilabel(app.ImageTimeSyncTab);
            app.YYMMDDeg230507Label.Text = '(YY-MM-DD; e.g. 23-05-07):';
            app.STARTSYNCHRONIZATIONButton = uibutton(app.ImageTimeSyncTab, 'push');
            app.STARTSYNCHRONIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSYNCHRONIZATIONButtonPushed, true);
            app.STARTSYNCHRONIZATIONButton.Text = 'START SYNCHRONIZATION!(>''-'')>';
            app.HHMMSSeg130901Label = uilabel(app.ImageTimeSyncTab);
            app.HHMMSSeg130901Label.Text = '(HH:MM:SS; e.g. 13:09:01):';
            app.chooseButton = uibutton(app.ImageTimeSyncTab, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.YEARLabel = uilabel(app.ImageTimeSyncTab);
            app.YEARLabel.Text = 'YEAR';
            app.MONTHLabel = uilabel(app.ImageTimeSyncTab);
            app.MONTHLabel.Text = 'MONTH';
            app.DAYSLabel = uilabel(app.ImageTimeSyncTab);
            app.DAYSLabel.Text = 'DAYS';
            app.CTDyear = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDyear.Limits = [0 99];
            app.CTDmon = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDmon.Limits = [1 12];
            app.CTDmon.Value = 1;
            app.CTDdays = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDdays.Limits = [1 31];
            app.CTDdays.Value = 1;
            app.FOSItimemin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FOSItimemin.Limits = [0 59];
            app.FOSItimesec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FOSItimesec.Limits = [0 59];
            app.Label = uilabel(app.ImageTimeSyncTab);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.ImageTimeSyncTab);
            app.Label_2.Text = ':';
            app.HOURLabel = uilabel(app.ImageTimeSyncTab);
            app.HOURLabel.Text = 'HOUR';
            app.MINLabel = uilabel(app.ImageTimeSyncTab);
            app.MINLabel.Text = 'MIN';
            app.SECLabel = uilabel(app.ImageTimeSyncTab);
            app.SECLabel.Text = 'SEC';
            app.CALIcameramin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcameramin.Limits = [0 59];
            app.CALIcamerasec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcamerasec.Limits = [0 59];
            app.Label_3 = uilabel(app.ImageTimeSyncTab);
            app.Label_3.Text = ':';
            app.Label_4 = uilabel(app.ImageTimeSyncTab);
            app.Label_4.Text = ':';
            app.CTDmin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDmin.Limits = [0 59];
            app.CTDsec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDsec.Limits = [0 59];
            app.Label_5 = uilabel(app.ImageTimeSyncTab);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.ImageTimeSyncTab);
            app.Label_6.Text = ':';
            app.CALICTDmin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDmin.Limits = [0 59];
            app.CALICTDsec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDsec.Limits = [0 59];
            app.Label_7 = uilabel(app.ImageTimeSyncTab);
            app.Label_7.Text = ':';
            app.Label_8 = uilabel(app.ImageTimeSyncTab);
            app.Label_8.Text = ':';
            app.Image = uiimage(app.ImageTimeSyncTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DSCF2675.jpg');
            app.Label_9 = uilabel(app.ImageTimeSyncTab);
            app.Label_9.Text = '-';
            app.Label_10 = uilabel(app.ImageTimeSyncTab);
            app.Label_10.Text = '-';
            app.FoSITIMEhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FoSITIMEhour.Limits = [0 23];
            app.CALIcamerahour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcamerahour.Limits = [0 23];
            app.CTDhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDhour.Limits = [0 23];
            app.CALICTDhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDhour.Limits = [0 23];
            app.pathdisp = uilabel(app.ImageTimeSyncTab);
            app.pathdisp.Text = '';
            app.ImageInputDirectoryLabel = uilabel(app.ImageTimeSyncTab);
            app.ImageInputDirectoryLabel.Text = 'Image Input Directory:';
            app.ImageFormatLabel = uilabel(app.ImageTimeSyncTab);
            app.ImageFormatLabel.Text = 'Image Format:';
            app.CTDDateEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CTDDateEditFieldLabel.Text = {'CTD Date: '; ''};
            app.FoSITIMEwihcalibrationclockEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.FoSITIMEwihcalibrationclockEditFieldLabel.Text = 'FoSI TIME (wih calibration clock):';
            app.CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel.Text = 'CALIBRATION CLOCK TIME (wih camera):';
            app.CTDTIMEwihcalibrationclockEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CTDTIMEwihcalibrationclockEditFieldLabel.Text = 'CTD TIME (wih calibration clock):';
            app.CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel.Text = 'CALIBRATION CLOCK TIME (wih CTD):';
            app.ImageSubtractionTab = uitab(app.TabGroup);
            app.ImageSubtractionTab.Title = 'Image Subtraction';
            app.ParticleProfilesTab = uitab(app.TabGroup);
            app.ParticleProfilesTab.Title = 'Particle Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FoSI_sync

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end