classdef FoSI_sync_multi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageTimeSyncTab                matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel  matlab.ui.control.Label
        CTDTIMEwihcalibrationclockEditFieldLabel  matlab.ui.control.Label
        CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel  matlab.ui.control.Label
        CTDDateEditFieldLabel           matlab.ui.control.Label
        ImageFormatLabel                matlab.ui.control.Label
        ImageInputDirectoryLabel        matlab.ui.control.Label
        pathdisp                        matlab.ui.control.Label
        CALICTDhour                     matlab.ui.control.NumericEditField
        CTDhour                         matlab.ui.control.NumericEditField
        CALIcamerahour                  matlab.ui.control.NumericEditField
        FoSITIMEhour                    matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        CALICTDsec                      matlab.ui.control.NumericEditField
        CALICTDmin                      matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        CTDsec                          matlab.ui.control.NumericEditField
        CTDmin                          matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CALIcamerasec                   matlab.ui.control.NumericEditField
        CALIcameramin                   matlab.ui.control.NumericEditField
        SECLabel                        matlab.ui.control.Label
        MINLabel                        matlab.ui.control.Label
        HOURLabel                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FOSItimesec                     matlab.ui.control.NumericEditField
        FOSItimemin                     matlab.ui.control.NumericEditField
        CTDdays                         matlab.ui.control.NumericEditField
        CTDmon                          matlab.ui.control.NumericEditField
        CTDyear                         matlab.ui.control.NumericEditField
        DAYSLabel                       matlab.ui.control.Label
        MONTHLabel                      matlab.ui.control.Label
        YEARLabel                       matlab.ui.control.Label
        chooseButton                    matlab.ui.control.Button
        HHMMSS24hourclockeg130901Label  matlab.ui.control.Label
        STARTSYNCHRONIZATIONButton      matlab.ui.control.Button
        YYMMDDeg230507Label             matlab.ui.control.Label
        ImageFormatDropDown             matlab.ui.control.DropDown
        TimeSynchronizationbetweenFoSIImagesandCTDLabel  matlab.ui.control.Label
        ImageSubtractionTab             matlab.ui.container.Tab
        Hyperlink_2                     matlab.ui.control.Hyperlink
        STARTSUBTRACTIONButton          matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        OutputDirectoryLabel            matlab.ui.control.Label
        pathdisp_4                      matlab.ui.control.Label
        chooseButton_4                  matlab.ui.control.Button
        Label_11                        matlab.ui.control.Label
        BlankImageDirectoryLabel        matlab.ui.control.Label
        pathdisp_3                      matlab.ui.control.Label
        chooseButton_3                  matlab.ui.control.Button
        SyncImageInputDirectoryLabel    matlab.ui.control.Label
        pathdisp_2                      matlab.ui.control.Label
        chooseButton_2                  matlab.ui.control.Button
        SubtractingtheImagePairsinSequenceLabel  matlab.ui.control.Label
        ParticleDataCTDTab              matlab.ui.container.Tab
        StationNoEditField              matlab.ui.control.NumericEditField
        StationNoEditFieldLabel         matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        valuerangesfrom0255Label_3      matlab.ui.control.Label
        valuerangesfrom0255Label_2      matlab.ui.control.Label
        valuerangesfrom0255Label        matlab.ui.control.Label
        CTDfileLabel                    matlab.ui.control.Label
        pathdisp_5                      matlab.ui.control.Label
        chooseButton_5                  matlab.ui.control.Button
        GrayscaleThresholdEditField     matlab.ui.control.NumericEditField
        GrayscaleThresholdEditFieldLabel  matlab.ui.control.Label
        SubtractedImageDirectoryLabel   matlab.ui.control.Label
        pathdisp_6                      matlab.ui.control.Label
        chooseButton_6                  matlab.ui.control.Button
        DataMatchingLabel               matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        PARTICLEANALYSISButton          matlab.ui.control.Button
        Hyperlink_4                     matlab.ui.control.Hyperlink
        VerticalProfilesTab             matlab.ui.container.Tab
        valuerangesfrom0255Label_6      matlab.ui.control.Label
        PLOTinNewWindowButton           matlab.ui.control.Button
        SmoothWindowEditField           matlab.ui.control.NumericEditField
        SmoothWindowLabel               matlab.ui.control.Label
        valuerangesfrom0255Label_5      matlab.ui.control.Label
        valuerangesfrom0255Label_4      matlab.ui.control.Label
        VerticalProfilePlottingLabel    matlab.ui.control.Label
        DepthoffieldEditField_3Label    matlab.ui.control.Label
        DepthoffieldEditField           matlab.ui.control.NumericEditField
        FoSITIMEwihcalibrationclockEditFieldLabel_9  matlab.ui.control.Label
        mpixelLabel                     matlab.ui.control.Label
        PixelResolutionEditField_2      matlab.ui.control.NumericEditField
        PixelResolutionEditField_2Label  matlab.ui.control.Label
        OutputDirLabel                  matlab.ui.control.Label
        pathdisp_9                      matlab.ui.control.Label
        chooseButton_9                  matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_8  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_7  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_6  matlab.ui.control.Label
        partsize_max                    matlab.ui.control.NumericEditField
        partsize_min                    matlab.ui.control.NumericEditField
        Label_16                        matlab.ui.control.Label
        CTDfileLabel_2                  matlab.ui.control.Label
        pathdisp_11                     matlab.ui.control.Label
        chooseButton_10                 matlab.ui.control.Button
        CombinedFileLabel               matlab.ui.control.Label
        pathdisp_10                     matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_4  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_3  matlab.ui.control.Label
        FoSITIMEwihcalibrationclockEditFieldLabel_2  matlab.ui.control.Label
        dep_max                         matlab.ui.control.NumericEditField
        dep_min                         matlab.ui.control.NumericEditField
        CTDFactorsLabel                 matlab.ui.control.Label
        chooseButton_8                  matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        Hyperlink_5                     matlab.ui.control.Hyperlink
    end


    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)

            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp.Text=inputpath;  % Sets the label text to be the selected path
            end

        end

        % Button pushed function: STARTSYNCHRONIZATIONButton
        function STARTSYNCHRONIZATIONButtonPushed(app, event)
            h = waitbar(0,'Please wait...');
            pause(1)
            path_dir=app.pathdisp.Text;
            hh_fosi=app.FoSITIMEhour.Value;
            mm_fosi=app.FOSItimemin.Value;
            ss_fosi=app.FOSItimesec.Value;
            fosi_time=[hh_fosi,mm_fosi,ss_fosi];

            hh_calcam=app.CALIcamerahour.Value; 
            mm_calcam=app.CALIcameramin.Value; 
            ss_calcam=app.CALIcamerasec.Value; 
            calcam_time=[hh_calcam,mm_calcam,ss_calcam];

            hh_ctd=app.CTDhour.Value;
            mm_ctd=app.CTDmin.Value;
            ss_ctd=app.CTDsec.Value;
            ctd_time=[hh_ctd,mm_ctd,ss_ctd];

            hh_calctd=app.CALICTDhour.Value;
            mm_calctd=app.CALICTDmin.Value;
            ss_calctd=app.CALICTDsec.Value;
            calctd_time=[hh_calctd,mm_calctd,ss_calctd];

            im_format=app.ImageFormatDropDown.Value;
            datei=[app.CTDyear.Value,app.CTDmon.Value,app.CTDdays.Value];
         
            fositimesynchronizer(path_dir,fosi_time,calcam_time,ctd_time,calctd_time,datei,im_format,h) 

        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            path_dir=app.pathdisp.Text;
            dir_sep = regexp(path_dir,'\','split');
            cast_name = dir_sep{end};
            save_dir = strcat(path_dir,'\',cast_name,' sync\');
            winopen(save_dir)
        end

        % Button pushed function: chooseButton_2
        function chooseButton_2Pushed(app, event)
            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp_2.Text=inputpath;  % Sets the label text to be the selected path
            end
        end

        % Button pushed function: chooseButton_3
        function chooseButton_3Pushed(app, event)
            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp_3.Text=inputpath;  % Sets the label text to be the selected path
            end
        end

        % Button pushed function: chooseButton_4
        function chooseButton_4Pushed(app, event)
            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp_4.Text=inputpath;  % Sets the label text to be the selected path
            end
        end

        % Button pushed function: STARTSUBTRACTIONButton
        function STARTSUBTRACTIONButtonPushed(app, event)
            h1 = waitbar(0,'Please wait...');
            pause(1)
            input_dir=app.pathdisp_2.Text;
            blank_dir=app.pathdisp_3.Text;
            output_dir=app.pathdisp_4.Text;
            blankImage=zeros(960,1280);
            if isempty(blank_dir)  % get the average of middle 100 images 
                FileList   = dir(fullfile(input_dir, '*-*'));
                % sort the images in folder as an ascending order(the order is not right for the initial order)
                [~, Index] = natsort({FileList.name});
                % return the images in new order into the list
                FileList   = FileList(Index);
                len=length(FileList);
                img_num=100;
            for i=round(len/2)-50:round(len/2)+49  % change 5 to 100 later
                image_name = FileList(i).name;
                blank=im2double((imread(strcat(input_dir,'\',image_name))));
                % First do a check for matching rows, columns, and number of color channels.  Then:
                blankImage = blankImage +  blank/img_num;
            end
            else
                FileList   = dir(fullfile(blank_dir, '*-*'));
                % sort the images in folder as an ascending order(the order is not right for the initial order)
                [~, Index] = natsort({FileList.name});
                % return the images in new order into the list
                FileList   = FileList(Index);
                len=length(FileList);
            for i=1:len  % change 5 to 100 later
                image_name = FileList(i).name;
                blank=im2double((imread(strcat(blank_dir,'\',image_name))));
                % First do a check for matching rows, columns, and number of color channels.  Then:
                blankImage = blankImage +  blank/len;
            end
            end

                dir_sep = regexp(input_dir,'\','split');
                cast_name = dir_sep{end-1};
            if isempty(output_dir) 
            % set(app.blankimagedisplay,'Units','pixels');
                opts = struct('WindowStyle','modal',... 
                              'Interpreter','tex');
                f = errordlg('Please select output directory.',...
                             'Output Dir Error', opts);
            else
                save_dir = strcat(output_dir,'\',cast_name,'_subtracted');
            end

            if isfolder(save_dir)
                rmdir(save_dir,'s');
            end
            mkdir(save_dir);

            for i = 1:length([1:2:len-1])
                j=2*i-1;
                % get the image name of the 2nd image in the list
                image_name = FileList(j).name;
                %split the name based on ' '
                a = regexp(image_name,' ','split');
                % get the variable of image number
                image_label = a{1};
                % read the number after image
                image_number=sscanf(image_label,'image%f');
                % get the image name of the 2nd image in subtraction
                new=image_name(end-20:end-4);
                new=replace(new,' ','_');
                % filter the image with even number   image_name_ms = FileList_match(1).name;
                image_name_1 = FileList(j).name;
                I1=im2double(imread(strcat(input_dir,'\',image_name_1)));
                %imshow(I1)
                image_name_2 = FileList(j+1).name;
                I2=im2double(imread(strcat(input_dir,'\',image_name_2)));
                %imshow(I2)
                [cor_I1]=correction_function_noclip(blankImage, I1);
                [cor_I2]=correction_function_noclip(blankImage, I2);
                % %%%figure
                % %imshow(cor_I1)
                % %%%figure
                % %imshow(cor_I2)
                diff=abs(cor_I1-cor_I2);
                % 
                %%%figure
            %     %diff(art_mask==1)=0;
                %imshow(diff)
                imwrite(diff,[save_dir '\diffgain' num2str(image_number) '_' num2str(new) '.bmp']);
                waitbar(i/length([1:2:len-1]),h1);
            end
        end

        % Callback function: Hyperlink_2
        function Hyperlink_2Clicked(app, event)
            input_dir=app.pathdisp_2.Text;
            output_dir=app.pathdisp_4.Text;
            dir_sep = regexp(input_dir,'\','split');
            cast_name = dir_sep{end-1};
            save_dir = strcat(output_dir,'\',cast_name,'_subtracted');
            winopen(save_dir)
        end

        % Button pushed function: chooseButton_6
        function chooseButton_6Pushed(app, event)
            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp_6.Text=inputpath;  % Sets the label text to be the selected path
            end
        end

        % Button pushed function: chooseButton_5
        function chooseButton_5Pushed(app, event)
            [filename, pathname] = uigetfile({'*.cnv';'*.csv'}, ...
                                            'File Selector');
            app.pathdisp_5.Text=strcat(pathname, filename);  % Sets the label text to be the selected path
        end

        % Button pushed function: PARTICLEANALYSISButton
        function PARTICLEANALYSISButtonPushed(app, event)
            h2 = waitbar(0,'Step1/3: Analyzing particle info from images...');
            pause(1)

            % convert the CTD files from cnv to csv

            [filepath,frontname, ext] = fileparts(app.pathdisp_6.Text);
            cast_name_con=regexp(frontname,'_subtracted','split');
            cast_name=cast_name_con{1};
            save_dir=strcat(filepath, '\',cast_name,'_analysis','\');
            if ~exist(save_dir)
                mkdir(save_dir)
            end
            % Extract the particle information from images

            myFolder = app.pathdisp_6.Text;
            % Change to whatever pattern you need.
            filePattern = fullfile(myFolder, '*.bmp*'); 
            theFiles = dir(filePattern);
            [~, Index] = natsort({theFiles.name});
            theFiles   = theFiles(Index);
            n=0;
            M={};

            for i=1:length(theFiles)
                baseFileName = theFiles(i).name;
                fullFileName = fullfile(myFolder, baseFileName);
            %     fprintf(1, 'Now reading %s', fullFileName);
            
            
                % set the threshold for binary
                T=app.GrayscaleThresholdEditField.Value;
                % get the path, name(mainly we get) and file extensions
                [filepath,name,ext] = fileparts(baseFileName);
                % seperate the data based on the ' ' into image number, date and time
                a = regexp(name,'_','split');
                % get the variable of image number
                image_label = a{1};
                % read the number after image
                [image_number,~,~]=sscanf(image_label,'diffgain%f');
                % read the date on the name of image
                % read the date on the name of image
                dat_{i}=baseFileName(end-20:end-13);
                % read the time on the name of image
                tim_{i}=baseFileName(end-11:end-4);
                % change the '-' to ':' on name of time
                tim_{i}= strrep(tim_{i},'-',':');
                new=baseFileName(end-20:end);
                folder = fileparts(which(baseFileName)); % Determine where demo folder is (works with all versions).
                fullFileName = fullfile(folder, baseFileName);
                originalImage = imread(strcat(myFolder,'\',fullFileName));
                % Check to make sure that it is grayscale, just in case the user substituted their own image.
                [rows, columns, numberOfColorChannels] = size(originalImage);
                thresholdValue = T;
                binaryImage = rem_simcan(originalImage,  thresholdValue); 
                blobMeasurements = regionprops(binaryImage, originalImage, 'all');%ABB
                numberOfBlobs = size(blobMeasurements, 1);
            
                % Loop over all blobs printing their measurements to the command window.
                for k = 1 : numberOfBlobs           % Loop through all blobs.
                %   fprintf(1,'%2d %2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f', image_number, k, meanGL, blobArea, blobPerimeter, blobCentroid, blobECD(k));%ABB
                % m=zeros(row_num,column_num);
                % m(i,:) = new_row;
                % M=[];
                Area = blobMeasurements(k).Area;
                MajorAxisLength=blobMeasurements(k).MajorAxisLength;
                MinorAxisLength=blobMeasurements(k).MinorAxisLength;
                EquivDiameter=blobMeasurements(k).EquivDiameter;
                Eccentricity=blobMeasurements(k).Eccentricity;
                Orientation=blobMeasurements(k).Orientation;
                FilledArea=blobMeasurements(k).FilledArea;
                Solidity=blobMeasurements(k).Solidity;
                Perimeter=blobMeasurements(k).Perimeter;
                MeanIntensity=blobMeasurements(k).MeanIntensity;
                Date = dat_{i};
                Timepoint = tim_{i};
                % save all the measurement results to a new cell
                [M{k+n,1}]=[image_number];
                [M{k+n,2}]=[k];
                [M{k+n,3}]=[Area];
                [M{k+n,4}]=[MajorAxisLength];
                [M{k+n,5}]=[MinorAxisLength];
                [M{k+n,6}]=[EquivDiameter ];
                [M{k+n,7}]=[Eccentricity];
                [M{k+n,8}]=[ Orientation];
                [M{k+n,9}]=[ FilledArea ];
                [M{k+n,10}]=[Solidity];
                [M{k+n,11}]=[Perimeter];
                [M{k+n,12}]=[MeanIntensity];
                [M{k+n,13}]=[Date];
                [M{k+n,14}]=[Timepoint];
                end
                % if there are no particles in the image, the values would be 0
                if isempty(k)==1
                Date = dat_{i};
                Timepoint = tim_{i};
                k=1;
                [M{k+n,1}]=[image_number];
                [M{k+n,2}]=[0];
                [M{k+n,3}]=[0];
                [M{k+n,4}]=[0];
                [M{k+n,5}]=[0];
                [M{k+n,6}]=[0];
                [M{k+n,7}]=[0];
                [M{k+n,8}]=[0];
                [M{k+n,9}]=[0];
                [M{k+n,10}]=[0];
                [M{k+n,11}]=[0];
                [M{k+n,12}]=[0];
                [M{k+n,13}]=[Date];
                [M{k+n,14}]=[Timepoint];
                end
                n=n+k;
                waitbar(i/length(theFiles),h2);
                end
                % M=sortrows(M,1);
                L=cell2table(M);
                L.Properties.VariableNames={'Image No.','Particle No.','Area','MajorAxisLength','MinorAxisLength','EquivDiameter','Eccentricity','Orientation','FilledArea','Solidity','Perimeter','MeanIntensity','Date','Time'};
                writetable(L,[save_dir 'T=' num2str(T) '_particle_data_' cast_name '.csv']); 
   
                           % combine with CTD data / get a csv option
                h3 = waitbar(0,'Step2/3: Extracting CTD data...');
                pause(1)
            % convert the CTD files from cnv to csv
            [ctdpath,frontname, ext] = fileparts(app.pathdisp_5.Text);
            cd(ctdpath)
            filename=strcat(frontname,ext);
            if contains(ext,'cnv')
            fid = fopen(filename);
            line1 = fgetl(fid);
            res=line1;
            while ischar(line1)
            line1 = fgetl(fid);
            res =char(res,line1);
          
            end
            fclose(fid);
            m=1;
            for k=1:height(res)
                if  contains(res(k,:),'name')
                    sep_str=regexp(res(k,:),':','split');
                    col_name{m}=sep_str{2};
                    m=m+1;
                end
                if contains(res(k,:),'Latitude')
                    sep_str=regexp(res(k,:),'=','split');
                    sep_str=sep_str{2};
                    sep_str=regexp(sep_str,' ','split');
                    sec_dig=str2num(sep_str{3});
                    lat=str2num(sep_str{2})+(sec_dig/60);
                end
                if contains(res(k,:),'Longitude')
                    sep_str=regexp(res(k,:),'=','split');
                    sep_str=sep_str{2};
                    sep_str=regexp(sep_str,' ','split');
                    sec_dig=str2num(sep_str{3});
                    lon=str2num(sep_str{2})+(sec_dig/60);
                end
                if contains(res(k,:),'*END*')
                    id_last=k;
                    continue
                end 
            end
            
            r=1;
            
            for j=id_last+1:height(res)
                sep_value=regexp(res(j,:),' ','split');
                f=0;    
                q=1;
                for p=1:length(sep_value)
                   
                    if ~isempty(str2num(sep_value{p}))
                        data(r,q)=str2num(sep_value{p});
                        q=q+1;
                        f=1;
                    end
                 
                 end
                 if f==1 
                    r=r+1;
                 else
                    continue;
                 end    
                 waitbar(j/length(id_last+1:height(res)),h3) 
            end
            for i=1:length(col_name)
             col_nospace{i}=strtrim(col_name{i});
            end
            m_tb=array2table(data);
            r_no=height(m_tb);
            lat_c=array2table(ones(r_no,1)*lat);
            lat_c.Properties.VariableNames={'Latitude [degrees]'}; 
            lon_c=array2table(ones(r_no,1)*lon);
            lon_c.Properties.VariableNames={'Longitude [degrees]'}; 
            st_no=app.StationNoEditField.Value;
            st_c=array2table(ones(r_no,1)*st_no);
            m_tb=[st_c,lat_c,lon_c,m_tb];
            col_nospace=['Station [#]','Latitude [degrees]','Longitude [degrees]',col_nospace];
            m_tb.Properties.VariableNames=col_nospace;
            writetable((m_tb),[save_dir,frontname,'.csv']);
            else 
                for q=1:2
                waitbar(q/2,h3) 
                end
            end
            % next combination with ctd
            h4 = waitbar(0,'Step3/3: Matching particle info with CTD data...');
            pause(1)
            if contains(ext,'cnv')
            data_ctd=m_tb;
            else
            data_ctd=readtable(app.pathdisp_5.Text);
            col_nospace=data_ctd.Properties.VariableNames;
            end
            n=1;
            Comb={};
            m=1;
            T=app.GrayscaleThresholdEditField.Value;
            po=data_ctd. Properties. VariableNames;
            col_dp=contains(po, 'Julian');
            julian_day=table2array(data_ctd(:,col_dp));
            % get the row number and column number of the CTD data
            [hour_ctd,minute_ctd,second_ctd]=julian_conversion(julian_day);
            data_particle=L;
            save_name=strcat('combine_',cast_name,'.csv');
            % get the time in image data
            time_point=table2cell(data_particle(:,14));
            % change the format of x into string as B
            time_p=string(time_point);
            for j=1:length(time_point)
            
            % split the string in the image data into hour, minute and second
                unt=time_p(j,1);
                P=strsplit(unt,':');
                hour=str2num(P{1});
                minute=str2num(P{2});
                second=str2num(P{3});
                % if the time points in 2 variabls match, the write down the data
                if ~isempty(find(hour_ctd==hour&minute_ctd==minute&second_ctd==second))==1
                    i=find(hour_ctd==hour&minute_ctd==minute&second_ctd==second); i=i(1);
                    for l_par=1:width(data_particle)
                        Comb{n,l_par}=table2cell(data_particle(j,l_par));
                    end
                    for l_ctdcol=1:length(po)
                        Comb{n,width(data_particle)+l_ctdcol}=table2cell(data_ctd(i,l_ctdcol));
                    end
                
                        %[image_number k Area MajorAxisLength  MinorAxisLength EquivDiameter Eccentricity Orientation FilledArea Solidity Perimeter MeanIntensity data time Conductivity   Conductivity2  temperature temperature2   pressure    oxy oxy2   fluorescence[ug/l]  fluorescence[mg/m^3]  BeamAttenuation  BeamTransmission PSU PSU2   density density2   depth   days    hours   min  sec ]
                    n=n+1;
                % else if we get the julian day not aligned with CTD time point, we will
                % need to approximate to closest depth
                else
                julian=hour/24+minute/24/60+second/24/60/60;
                [val,idx]=min(abs(500*(julian_day-floor(julian_day))-500*julian));
                idx=idx(1);
                if floor(julian_day(end))~=floor(julian_day(1)) && julian>0.8
                    julian = julian-1;
                end
                if julian<julian_day(1)-floor(julian_day(end)) || julian>julian_day(end)-floor(julian_day(end))
                   ;
                else
                
                        for l_par=1:width(data_particle)
                          Comb{n,l_par}=table2cell(data_particle(j,l_par));
                        end
                        for l_ctdcol=1:length(po)
                          Comb{n,width(data_particle)+l_ctdcol}=table2cell(data_ctd(idx,l_ctdcol));
                        end
                
                n=n+1;
                end
                end
               
            waitbar(j/length(time_point),h4);

            % if it goes to next loop, already write data at the same time point and the next one does not match, then end the loop and for the next time point, start from this row   
            end
            % pp=vertcat(Comb{:,1};Comb{:,2});
            L=cell2table(Comb);
            L.Properties.VariableNames=['Image No.','Particle No.','Area','MajorAxisLength','MinorAxisLength','EquivDiameter','Eccentricity','Orientation','FilledArea','Solidity','Perimeter','MeanIntensity','Date','Time',col_nospace];

            writetable(splitvars(L),[save_dir,save_name]);
        end

        % Callback function: Hyperlink_4
        function Hyperlink_4Clicked(app, event)
            [filepath,frontname, ext] = fileparts(app.pathdisp_6.Text);
            cast_name_con=regexp(frontname,'_subtracted','split');
            cast_name=cast_name_con{1};
            save_dir=strcat(filepath, '\',cast_name,'_analysis','\');
            winopen(save_dir)
        end

        % Button pushed function: chooseButton_8
        function chooseButton_8Pushed(app, event)
            [filename, pathname] = uigetfile({'*.csv'}, 'File Selector');
            app.pathdisp_10.Text=strcat(pathname, filename);  % Sets the label text to be the selected path

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed2(app, event)
            h5 = waitbar(0,'Step1/2: Binning particle data into every 1m...');
            pause(1)

            [combpath,frontname, ext] = fileparts(app.pathdisp_10.Text);
            comb=readtable(app.pathdisp_10.Text);
            col_comb=readcell(app.pathdisp_10.Text,'Range','1:1');
            col_ctd=readcell(app.pathdisp_11.Text,'Range','1:1');
            col_ctd=col_ctd(4:end);
            dd = uidropdown(app.Panel_4,"Items",col_ctd);
              % 
            m_big={};
            reso=app.PixelResolutionEditField_2.Value;
            sample_distance=app.DepthoffieldEditField.Value;
            cat=comb;
            cat_ori=comb;
            img_num_ori=cat{:,1};
            img_num_ori_uni=unique(img_num_ori);
            ctd_rest_ori=cat{:,15:end};
            esd=cat{:,6};    
            if ~isempty(app.partsize_min.Value)&~isempty(app.partsize_max.Value)
                 ind_delete=find(esd>app.partsize_max.Value/reso|esd<app.partsize_min.Value/reso);
                 cat{ind_delete,2:12}=0;
            end
% %         if height(cat)>0  % decide if there are particles in the comb data 
                img_num=cat{:,1};
                image_idx=findgroups(img_num);
                img_num_uni=unique(img_num);
                particle_idx=cat{:,2};
% %         particle_no=groupsummary(particle_idx,image_idx,'max');
                particle_no=groupsummary(particle_idx,image_idx,'nnz');
                volume=4/3.*pi.*(cat{:,6}/2).^3;
                area=pi.*(cat{:,6}/2).^2;
                total_area=groupsummary(area,image_idx,'sum');
                total_volume=groupsummary(volume,image_idx,'sum');
                ctd_rest=cat{:,15:end};
                ctd_rest=groupsummary(ctd_rest,image_idx,'mean');
                idx=max(image_idx);
                date={};
                time={};
            for i=1:idx
                id_uni=find(image_idx==i);
                date=[date;cat(id_uni(1),13)];
                time=[time;cat(id_uni(1),14)]; 
            end   
            m=[table(img_num_uni,particle_no,total_area,total_volume,table2cell(date),table2cell(time)),array2table(ctd_rest)];
            m_big=vertcat(m_big,m);
            name_col_1={'Image No.','Particle [No./L]','Total particle area [px/frame]','Total particle volume [ppm]','Date','Time'};
            name_col_2=col_comb(15:end);
            m_big.Properties.VariableNames=[name_col_1,name_col_2];
            % % down-cast (duplicate depth in two-way cast)
            % 
            col_nm=m_big.Properties.VariableNames;
            col_id=contains(col_nm, 'Depth');
            depth_bin=m_big{:,col_id};
            idx_down=max(find(depth_bin==max(depth_bin)));
            m_big_down=m_big(1:idx_down,:);
            depth_bin=m_big_down{:,col_id};
            depth_idx=findgroups(depth_bin);
            depth_group_uni=unique(depth_idx);
            depth_bin_uni=unique(depth_bin);
            particle_no_bin=groupsummary(m_big_down{:,2},depth_idx,'mean')/((reso/10000)^2*960*1280*sample_distance*2/1000);  % the reso = reso um/px
            total_area_bin=groupsummary(m_big_down{:,3},depth_idx,'mean');
            total_volume_bin=groupsummary(m_big_down{:,4},depth_idx,'mean')*((reso/10000)^3)/((reso/10000)^2*960*1280*sample_distance*2)*10^6;
            ctd_rest_perim=m_big_down{:,7:end};
            ctd_rest_bin=groupsummary(ctd_rest_perim,depth_idx,'mean');
            ctd_rest_bin(:,find(col_id==1)-6)=[];
            date_bin={};
            time_bin={};
            

            for i = depth_group_uni'
                id_bin=find(depth_idx==i);
                date_bin=[date_bin;m_big_down(id_bin(1),5)];
                time_bin=[time_bin;m_big_down(id_bin(1),6)]; 
            end
            
            m_bin_ori_down=[table(depth_bin_uni,particle_no_bin,total_area_bin,total_volume_bin,table2cell(date_bin),table2cell(time_bin)),array2table(ctd_rest_bin)];
            name_col_1={'Depth [m]','Particle Abundance [No./L]','Total particle area [px/frame]','Total particle volume [ppm]','Date','Time'};
            col_id_rm=contains(name_col_2, 'Depth');
            name_col_2(:,col_id_rm)=[];
            name_col=[name_col_1,name_col_2];
            
            m_bin_ori_down=renamevars(m_bin_ori_down,1:width(m_bin_ori_down),name_col);
            
            %
            % find missing data in the table
            ori_ctd=csvread(app.pathdisp_11.Text,1,0);
            uniqueVals = unique( ori_ctd(:,1) );
               % Count the number of instances of each of the unique vals
            if length(ori_ctd(:,1))==hist( ori_ctd(:,1) , uniqueVals )'
                st_coor=ori_ctd(:,1:3);
                ori_ctd=ori_ctd(:,4:end);
            end
            miss_dep=[];
            m_bin_down=m_bin_ori_down;
            dp=m_bin_down{:,1};
            dp_ctd=ori_ctd(:,1);
            idx_max_dp_ctd=find(dp_ctd==max(dp));
            dp_ctd_down=dp_ctd(1:idx_max_dp_ctd);
            
            if dp(length(dp))-dp(1)+1~=length(dp)
                for i = 1:length(dp_ctd_down)-1
                    dp=m_bin_down{:,1};
                    if dp(i)~=dp(i+1)-1
            
                        miss_dep=[miss_dep;dp(i)+1];
            
                        point_ms=dp(i)+1;
                        id_ms_ctd=find(dp_ctd_down==point_ms);
                        id_ms_img=min(find(dp==dp(i)));
                        pre=m_bin_down(1:id_ms_img,:);
                        post=m_bin_down(id_ms_img+1:height(m_bin_down),:);
                        column_ms=[array2table([point_ms,NaN,NaN,NaN,NaN,NaN],'VariableNames',name_col_1),array2table(ori_ctd(id_ms_ctd,2:12),'VariableNames',name_col_2)];
            
                        m_bin_down=[pre;column_ms;post];
                    end
                   
                end
            end

            if height(m_big)==height(m_big_down)
                m_bin=[m_bin_down];
            else
            % up-cast (duplicate depth in two-way cast)
            
            depth_bin=m_big{:,col_id};
            idx_up=max(find(depth_bin==max(depth_bin)));
            m_big_up=m_big(idx_up+1:length(depth_bin),:);
            depth_bin=m_big_up{:,col_id};
            depth_idx=findgroups(depth_bin);
            depth_group_uni=unique(depth_idx);
            depth_bin_uni=unique(depth_bin);
            particle_no_bin=groupsummary(m_big_up{:,2},depth_idx,'mean')/(2*(reso/10000)^2*960*1280*sample_distance/1000);
            total_area_bin=groupsummary(m_big_up{:,3},depth_idx,'mean');
            total_volume_bin=groupsummary(m_big_up{:,4},depth_idx,'mean')*((reso/10000)^3)/((reso/10000)^2*960*1280*sample_distance*2)*10^6;
            ctd_rest_perim=m_big_up{:,7:end};
            ctd_rest_bin=groupsummary(ctd_rest_perim,depth_idx,'mean');
            ctd_rest_bin(:,find(col_id==1)-6)=[];
            date_bin={};
            time_bin={};
            
            for i = depth_group_uni'
                    id_bin=find(depth_idx==i);
                    date_bin=[date_bin;m_big_up(id_bin(1),5)];
                    time_bin=[time_bin;m_big_up(id_bin(1),6)]; 
            end
            
            m_bin_ori_up=[table(depth_bin_uni,particle_no_bin,total_area_bin,total_volume_bin,table2cell(date_bin),table2cell(time_bin)),array2table(ctd_rest_bin)];
            m_bin_ori_up=flip(m_bin_ori_up);
            m_bin_ori_up=renamevars(m_bin_ori_up,1:width(m_bin_ori_up),name_col);
            
            miss_dep=[];
            dp_ctd=ori_ctd(:,1);
            idx_max_dp_ctd=find(dp_ctd==max(dp_ctd));
            dp_ctd_up=dp_ctd(idx_max_dp_ctd+1:length(dp_ctd));
            ctd_up=ori_ctd(idx_max_dp_ctd+1:length(dp_ctd),:);
            m_bin_up=m_bin_ori_up;
            dp=table2array(m_bin_up(:,1));
            if dp(1)~=max(dp_ctd)-1
               for i = 1:max(dp_ctd)-dp(1)-1 
                   id_ms_ctd=max(dp_ctd)-dp(1)-i;
                   column_ms=[array2table([ctd_up(id_ms_ctd,1),NaN,NaN,NaN,NaN,NaN],'VariableNames',name_col_1),array2table(ctd_up(id_ms_ctd,2:end),'VariableNames',name_col_2)];
            
                   m_bin_up=[column_ms;m_bin_up];
               end
            end
            if abs(dp(end)-dp(1))+1~=length(dp)
                for i = 1:length(dp_ctd_up)-1
                    dp=m_bin_up{:,1};
                    if dp(i)~=dp(i+1)+1
            
                        miss_dep=[miss_dep;dp(i)-1];
            
                        point_ms=dp(i)-1;
                        id_ms_ctd=find(dp_ctd_up==point_ms);
                        id_ms_img=min(find(dp==dp(i)));
                        pre=m_bin_up(1:id_ms_img,:);
                        post=m_bin_up(id_ms_img+1:height(m_bin_up),:);
                        column_ms=[array2table([point_ms,NaN,NaN,NaN,NaN,NaN],'VariableNames',name_col_1),array2table(ctd_up(id_ms_ctd,2:end),'VariableNames',name_col_2)];
            
                        m_bin_up=[pre;column_ms;post];
                    end


                end
            end
            
            m_bin=[m_bin_down;m_bin_up];
            end

            name=m_bin.Properties.VariableNames;
            name_fir={};
            name_sec={};
            for g=1:length(name)
                if contains(name(g), '[')
                   name_sp=regexp(name(g),'[','split');
                   name_sp=name_sp{1};
                   name_fir{g}=name_sp{1};
                   name_sec{g}=['[',name_sp{2}];
                   if contains(name(g), 'Elapsed')
                     name_fir{g}=[name_sp{2}(1:end-1),' Elapsed'];
                   end
                else
                   name_fir{g}=name{g};
                   name_sec{g}={'  '};
                end
                waitbar(g/(length(name)),h5);

            end
            data=table2cell(m_bin);
            data_ad=[name_sec;data];
            data_ad=cell2table(data_ad);
            data_ad.Properties.VariableNames=name_fir;
            if length(ori_ctd(:,1))==hist( ori_ctd(:,1) , uniqueVals )'
            st_info=unique(st_coor)';
            lo_unit{1,1}=['#'];
            lo_unit{1,2}=['lat'];
            lo_unit{1,3}=['lon'];
            lo_data=[num2cell(ones(height(data_ad)-1,3).*st_info)];
            info_col=array2table([lo_unit;lo_data]);
            info_col.Properties.VariableNames={'Station','Latitude','Longitude'};
            data_ad=[info_col,data_ad];
            end
            sum_name=strrep(frontname,'combine','vertp_1m');
            if ~isempty(app.pathdisp_9.Text)
                save_path=app.pathdisp_9.Text;
            else
                save_path=combpath;
            end
            writetable(data_ad,[save_path,'\',sum_name,'_raw.csv']);

            % interpolate the missing values in some depths
            
            % clear
            h6 = waitbar(0,'Step2/2: Interpolating the missing data points...');
            pause(1)
            
            data=readcell([save_path,'\',sum_name,'_raw.csv']);
            colname_f=data(1,:);
            name_sec=data(2,:);
            data=cell2table(data(3:end,:));
            data.Properties.VariableNames=colname_f;
            m=0;
            [full_row,~]=size(data);
            uniqueVals = unique( table2array(data(:,1) ));
           % Count the number of instances of each of the unique vals
            if length(table2array(data(:,1)))==hist( table2array(data(:,1)) , uniqueVals )'
                conc_no=0;
            else
                conc_no=3;
            end
            depth=double(table2array(data(:,4-conc_no)));
            ind_max_depth=find(depth==max(depth));
            
            % pchip for the downward cast first
            data_particle=data(1:ind_max_depth,:);
            depth=table2array(data_particle(:,4-conc_no));  % read the data of depth
            number=table2array(data_particle(:,5-conc_no));  % read the data of number
            avevol=table2array(data_particle(:,6-conc_no));  % read the data of avevol
            totvol=table2array(data_particle(:,7-conc_no));  % read the data of totvol
            ind_valid=find(~isnan(number));
            if iscell(depth)
                depth=str2double(depth);
            end
            if iscell(number)
                number=str2double(number);
            end
            if iscell(avevol)
                avevol=str2double(avevol);
            end
            if iscell(totvol)
                totvol=str2double(totvol);
            end
            ind=find(depth==max(depth));
            
            [ss,pp]=size(depth);
            % if there are any NaN value in any data, use pchip to fill a number based
            % on other data
            for i=1:ss
                if ~isnan(number(i))
                    m=1;
                elseif m==1
                  if isnan(number(i))
                     number(i)=pchip(depth,number,depth(i));
                     number(i);
            
                  end
                  if isnan(avevol(i))
                    avevol(i)=pchip(depth,avevol,depth(i));
                    avevol(i);
            
                  end
                  if isnan(totvol(i))
                    totvol(i)=pchip(depth,totvol,depth(i));
                    totvol(i);
            
            
                  end
                end
                % if there are any data<0, which is not realistic, then leave it as NaN
              if number(i)<0| avevol(i)<0| totvol(i)<0
                number(i)=nan;
                avevol(i)=nan;
                totvol(i)=nan;
                
              end
              waitbar(i/ss,h6);
            end
            
            
            data_particle(:,5-conc_no)=array2table(number(:));
            data_particle(:,6-conc_no)=array2table(avevol(:));
            data_particle(:,7-conc_no)=array2table(totvol(:));
            
            if min(ind_valid)~=1
            data_particle(1:min(ind_valid),5-conc_no)=array2table(nan([0:min(ind_valid)-1,1]));
            data_particle(1:min(ind_valid),6-conc_no)=array2table(nan([0:min(ind_valid)-1,1]));
            data_particle(1:min(ind_valid),7-conc_no)=array2table(nan([0:min(ind_valid)-1,1]));
            end
            
            data_particle_down=data_particle;
            
            if ind_max_depth<size(data,1);
            % pchip for the upward cast later
            data_particle=data(ind_max_depth+1:end,:);
            depth=table2array(data_particle(:,4-conc_no));  % read the data of depth
            number=table2array(data_particle(:,5-conc_no));  % read the data of number
            avevol=table2array(data_particle(:,6-conc_no));  % read the data of avevol
            totvol=table2array(data_particle(:,7-conc_no));  % read the data of totvol
            ind_valid=find(~isnan(number));
            if iscell(depth)
                depth=str2double(depth);
            end
            if iscell(number)
                number=str2double(number);
            end
            if iscell(avevol)
                avevol=str2double(avevol);
            end
            if iscell(totvol)
                totvol=str2double(totvol);
            end
            
            
            [ss,pp]=size(depth);
             
            % if there are any NaN value in any data, use pchip to fill a number based
            % on other data
            for i=1:ss
                if ~isnan(number(i))
                    m=1;
                elseif m==1
              if isnan(number(i))
                 number(i)=pchip(depth,number,depth(i));
                 number(i);
             
              end
              if isnan(avevol(i))
                avevol(i)=pchip(depth,avevol,depth(i));
                avevol(i);
             
              end
              if isnan(totvol(i))
                totvol(i)=pchip(depth,totvol,depth(i));
                totvol(i);
             
             
              end
                end
                % if there are any data<0, which is not realistic, then leave it as NaN
              if number(i)<0| avevol(i)<0| totvol(i)<0
                number(i)=nan;
                avevol(i)=nan;
                totvol(i)=nan;
                
              end
                
            end
            
            
            data_particle(:,5-conc_no)=array2table(number(:));
            data_particle(:,6-conc_no)=array2table(avevol(:));
            data_particle(:,7-conc_no)=array2table(totvol(:));
            
            data_particle_up=data_particle;
            
            data_particle=[data_particle_down;data_particle_up];
            end
                        data_particle=table2cell(data_particle);
                        data_ad=[name_sec;data_particle];
                        data_ad=array2table(data_ad);
                        data_ad.Properties.VariableNames=colname_f;
            writetable(data_ad,[save_path,'\',sum_name,'_interpolation.csv']);

        end

        % Button pushed function: chooseButton_10
        function chooseButton_10Pushed(app, event)
            [filename, pathname] = uigetfile({'*.csv'}, 'File Selector');
            app.pathdisp_11.Text=strcat(pathname, filename);  % Sets the label text to be the selected path

        end

        % Button pushed function: chooseButton_9
        function chooseButton_9Pushed(app, event)
            inputpath=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            %app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on'; 
            if ischar(inputpath)||iscell(inputpath)||isstring(inputpath)||iscategorical(inputpath)
            app.pathdisp_9.Text=inputpath;  % Sets the label text to be the selected path
            end
        end

        % Callback function: Hyperlink_5
        function Hyperlink_5Clicked(app, event)
            if ~isempty(app.pathdisp_9.Text)
                save_path=app.pathdisp_9.Text;
            else
                [combpath,~,~] = fileparts(app.pathdisp_10.Text);
                save_path=combpath;
            end
            winopen(save_path)
        end

        % Button pushed function: PLOTinNewWindowButton
        function PLOTinNewWindowButtonPushed(app, event)
            [combpath,frontname, ext] = fileparts(app.pathdisp_10.Text);
            if ~isempty(app.pathdisp_9.Text)
                save_path=app.pathdisp_9.Text;
            else
                save_path=combpath;
            end
            sum_name=strrep(frontname,'combine','vertp_1m');
            data=readcell([save_path,'\',sum_name,'_interpolation.csv']);
            data=data(3:end,:);
            st_name='Unknown';
            uniqueVals = unique(cell2mat(data(:,1) ));
            if length(cell2mat(data(:,1) ))==hist(cell2mat(data(:,1) ), uniqueVals )'
                st_name=cell2mat(data(4,1));
                data=data(:,4:end);
            end
            depth=cell2mat(data(:,1));
            ind_max_depth=find(depth==max(depth));
            m=data(1:ind_max_depth,:);

            num=cell2mat((m(:,2)));
            avervol=cell2mat((m(:,3)));
            totvol=cell2mat((m(:,4)));
            depth=cell2mat((m(:,1)));
            ind_max_depth=find(depth==max(depth));
                        
            if iscell(num)
                num=str2double(num);
            end
            if iscell(avervol)
                avervol=str2double(avervol);
            end
            if iscell(totvol)
                totvol=str2double(totvol);
            end
            if iscell(depth)
                depth=str2double(depth);
            end

             
            if ~isempty(app.dep_min.Value)&~isempty(app.dep_max.Value)
                dep_min=app.dep_min.Value;
                dep_max=app.dep_max.Value;
                if dep_min<depth(1)
                    id_min=1;
                else
                    id_min=find(depth==dep_min);
                end
                id_max=find(depth==dep_max);
                if isempty(id_max) 
            % set(app.blankimagedisplay,'Units','pixels');
                opts = struct('WindowStyle','modal',... 
                              'Interpreter','tex');
                f = errordlg('The depth values fall outside the range of the current dataset range.',...
                             'Output Dir Error', opts);
                end
                depth=depth(id_min:id_max);
                num=num(id_min:id_max);
                avervol=avervol(id_min:id_max);
                totvol=totvol(id_min:id_max);
            end
            
            smooth_win=app.SmoothWindowEditField.Value;
            num_sm=nanfastsmooth(num,smooth_win,1,0);
            avervol_sm=nanfastsmooth(avervol,smooth_win,1,0);
            totvol_sm=nanfastsmooth(totvol,smooth_win,1,0);
            depth_sm=depth;%mav_41_avtotpvol=smooth(BMass,); 

            t=figure('Position',[1,20,400,700]); 
            % plot temperature vs pressure
            hl1=plot(num_sm,depth_sm,'k-');
            % assign current axis handle to variable for later reference if needed
            ax1=gca;
            % set properties of the axes
            
            set(ax1,'XMinorTick','on','ydir','reverse', 'box','on','xcolor',get(hl1,'color'))

            % add title to plot
            htitle = title(['Vertical Particle Distribution of St.' num2str(st_name)]);
            % If you want station and date stamp use: htitle = title(['num2str(Station(1)),'  ', datestr(now)]);
            set(hl1,'LineWidth',2);
            xlabel('Particle Abundance(#/L)','Fontsize',15)
            ylabel('Depth(m)','Fontsize',15)
            % % add 1st floating axis for the second parameter plotted
            [hl2,ax2,ax3] = floatAxisX(totvol_sm,depth,'b-','Total Particle Volume(ppm)');
            set(hl2,'LineWidth',2);
            set(ax3,'Fontsize',15);
            set(ax2,'XMinorTick','on','ydir','reverse', 'box','on','xcolor',get(hl2,'color'))

            %
            print(t,'-dbmp',[save_path '\Vertical Particle Distribution of St.' num2str(st_name) '.bmp'])
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTimeSyncTab = uitab(app.TabGroup);
            app.ImageTimeSyncTab.Title = 'Image Time Sync';
            app.TimeSynchronizationbetweenFoSIImagesandCTDLabel = uilabel(app.ImageTimeSyncTab);
            app.TimeSynchronizationbetweenFoSIImagesandCTDLabel.Text = 'Time Synchronization between FoSI Images and CTD';
            app.ImageFormatDropDown = uidropdown(app.ImageTimeSyncTab);
            app.ImageFormatDropDown.Items = {'.bmp', '.tiff', '.jpg', '.png'};
            app.ImageFormatDropDown.Value = '.bmp';
            app.YYMMDDeg230507Label = uilabel(app.ImageTimeSyncTab);
            app.YYMMDDeg230507Label.Text = '(YY-MM-DD; e.g. 23-05-07):';
            app.STARTSYNCHRONIZATIONButton = uibutton(app.ImageTimeSyncTab, 'push');
            app.STARTSYNCHRONIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSYNCHRONIZATIONButtonPushed, true);
            app.STARTSYNCHRONIZATIONButton.Text = 'START SYNCHRONIZATION!(>''-'')>';
            app.HHMMSS24hourclockeg130901Label = uilabel(app.ImageTimeSyncTab);
            app.HHMMSS24hourclockeg130901Label.Text = '(HH:MM:SS; 24-hour clock; e.g. 13:09:01):';
            app.chooseButton = uibutton(app.ImageTimeSyncTab, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.YEARLabel = uilabel(app.ImageTimeSyncTab);
            app.YEARLabel.Text = 'YEAR';
            app.MONTHLabel = uilabel(app.ImageTimeSyncTab);
            app.MONTHLabel.Text = 'MONTH';
            app.DAYSLabel = uilabel(app.ImageTimeSyncTab);
            app.DAYSLabel.Text = 'DAYS';
            app.CTDyear = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDyear.Limits = [0 99];
            app.CTDyear.AllowEmpty = 'on';
            app.CTDyear.Value = [];
            app.CTDmon = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDmon.Limits = [1 12];
            app.CTDmon.AllowEmpty = 'on';
            app.CTDmon.Value = [];
            app.CTDdays = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDdays.Limits = [1 31];
            app.CTDdays.AllowEmpty = 'on';
            app.CTDdays.Value = [];
            app.FOSItimemin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FOSItimemin.Limits = [0 59];
            app.FOSItimesec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FOSItimesec.Limits = [0 59];
            app.Label = uilabel(app.ImageTimeSyncTab);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.ImageTimeSyncTab);
            app.Label_2.Text = ':';
            app.HOURLabel = uilabel(app.ImageTimeSyncTab);
            app.HOURLabel.Text = 'HOUR';
            app.MINLabel = uilabel(app.ImageTimeSyncTab);
            app.MINLabel.Text = 'MIN';
            app.SECLabel = uilabel(app.ImageTimeSyncTab);
            app.SECLabel.Text = 'SEC';
            app.CALIcameramin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcameramin.Limits = [0 59];
            app.CALIcamerasec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcamerasec.Limits = [0 59];
            app.Label_3 = uilabel(app.ImageTimeSyncTab);
            app.Label_3.Text = ':';
            app.Label_4 = uilabel(app.ImageTimeSyncTab);
            app.Label_4.Text = ':';
            app.CTDmin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDmin.Limits = [0 59];
            app.CTDsec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDsec.Limits = [0 59];
            app.Label_5 = uilabel(app.ImageTimeSyncTab);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.ImageTimeSyncTab);
            app.Label_6.Text = ':';
            app.CALICTDmin = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDmin.Limits = [0 59];
            app.CALICTDsec = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDsec.Limits = [0 59];
            app.Label_7 = uilabel(app.ImageTimeSyncTab);
            app.Label_7.Text = ':';
            app.Label_8 = uilabel(app.ImageTimeSyncTab);
            app.Label_8.Text = ':';
            app.Image = uiimage(app.ImageTimeSyncTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DSCF2675.jpg');
            app.Label_9 = uilabel(app.ImageTimeSyncTab);
            app.Label_9.Text = '-';
            app.Label_10 = uilabel(app.ImageTimeSyncTab);
            app.Label_10.Text = '-';
            app.FoSITIMEhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.FoSITIMEhour.Limits = [0 23];
            app.CALIcamerahour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALIcamerahour.Limits = [0 23];
            app.CTDhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CTDhour.Limits = [0 23];
            app.CALICTDhour = uieditfield(app.ImageTimeSyncTab, 'numeric');
            app.CALICTDhour.Limits = [0 23];
            app.pathdisp = uilabel(app.ImageTimeSyncTab);
            app.pathdisp.Text = '';
            app.ImageInputDirectoryLabel = uilabel(app.ImageTimeSyncTab);
            app.ImageInputDirectoryLabel.Text = 'Image Input Directory:';
            app.ImageFormatLabel = uilabel(app.ImageTimeSyncTab);
            app.ImageFormatLabel.Text = 'Image Format:';
            app.CTDDateEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CTDDateEditFieldLabel.Text = {'CTD Date: '; ''};
            app.FoSITIMEwihcalibrationclockEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.FoSITIMEwihcalibrationclockEditFieldLabel.Text = 'FoSI TIME (with calibration clock):';
            app.CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CALIBRATIONCLOCKTIMEwihcameraEditFieldLabel.Text = 'CALIBRATION CLOCK TIME (with camera):';
            app.CTDTIMEwihcalibrationclockEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CTDTIMEwihcalibrationclockEditFieldLabel.Text = 'CTD TIME (with calibration clock):';
            app.CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel = uilabel(app.ImageTimeSyncTab);
            app.CALIBRATIONCLOCKTIMEwihCTDEditFieldLabel.Text = 'CALIBRATION CLOCK TIME (with CTD):';
            app.Hyperlink = uihyperlink(app.ImageTimeSyncTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'click here to open the output directory';
            app.ImageSubtractionTab = uitab(app.TabGroup);
            app.ImageSubtractionTab.Title = 'Image Subtraction';
            app.SubtractingtheImagePairsinSequenceLabel = uilabel(app.ImageSubtractionTab);
            app.SubtractingtheImagePairsinSequenceLabel.Text = 'Subtracting the Image Pairs in Sequence';
            app.chooseButton_2 = uibutton(app.ImageSubtractionTab, 'push');
            app.chooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_2Pushed, true);
            app.chooseButton_2.Text = 'choose';
            app.pathdisp_2 = uilabel(app.ImageSubtractionTab);
            app.pathdisp_2.Text = '';
            app.SyncImageInputDirectoryLabel = uilabel(app.ImageSubtractionTab);
            app.SyncImageInputDirectoryLabel.Text = 'Sync Image Input Directory:';
            app.chooseButton_3 = uibutton(app.ImageSubtractionTab, 'push');
            app.chooseButton_3.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_3Pushed, true);
            app.chooseButton_3.Text = 'choose';
            app.pathdisp_3 = uilabel(app.ImageSubtractionTab);
            app.pathdisp_3.Text = '';
            app.BlankImageDirectoryLabel = uilabel(app.ImageSubtractionTab);
            app.BlankImageDirectoryLabel.Text = 'Blank Image Directory:';
            app.Label_11 = uilabel(app.ImageSubtractionTab);
            app.Label_11.Text = 'If blank, use the moving average of 100 images in the middle of the sequence to obtain a blank image.';
            app.chooseButton_4 = uibutton(app.ImageSubtractionTab, 'push');
            app.chooseButton_4.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_4Pushed, true);
            app.chooseButton_4.Text = 'choose';
            app.pathdisp_4 = uilabel(app.ImageSubtractionTab);
            app.pathdisp_4.Text = '';
            app.OutputDirectoryLabel = uilabel(app.ImageSubtractionTab);
            app.OutputDirectoryLabel.Text = 'Output Directory:';
            app.Label_12 = uilabel(app.ImageSubtractionTab);
            app.Label_12.Text = 'Copy the output directory from "Image Time Sync" Tab.';
            app.Label_13 = uilabel(app.ImageSubtractionTab);
            app.Label_13.Text = 'Please specify the paths.';
            app.STARTSUBTRACTIONButton = uibutton(app.ImageSubtractionTab, 'push');
            app.STARTSUBTRACTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSUBTRACTIONButtonPushed, true);
            app.STARTSUBTRACTIONButton.Text = 'START SUBTRACTION!(>''-'')>';
            app.Hyperlink_2 = uihyperlink(app.ImageSubtractionTab);
            app.Hyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_2Clicked, true);
            app.Hyperlink_2.Text = 'click here to open the output directory';
            app.ParticleDataCTDTab = uitab(app.TabGroup);
            app.ParticleDataCTDTab.Title = 'Particle Data & CTD';
            app.Panel_3 = uipanel(app.ParticleDataCTDTab);
            app.Panel_3.Title = '  ';
            app.Hyperlink_4 = uihyperlink(app.Panel_3);
            app.Hyperlink_4.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_4Clicked, true);
            app.Hyperlink_4.Text = 'click here to open the output directory';
            app.PARTICLEANALYSISButton = uibutton(app.Panel_3, 'push');
            app.PARTICLEANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @PARTICLEANALYSISButtonPushed, true);
            app.PARTICLEANALYSISButton.Text = 'PARTICLE ANALYSIS! (>''-'')>';
            app.DataMatchingLabel = uilabel(app.ParticleDataCTDTab);
            app.DataMatchingLabel.Text = 'Data Matching';
            app.chooseButton_6 = uibutton(app.ParticleDataCTDTab, 'push');
            app.chooseButton_6.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_6Pushed, true);
            app.chooseButton_6.Text = 'choose';
            app.pathdisp_6 = uilabel(app.ParticleDataCTDTab);
            app.pathdisp_6.Text = '';
            app.SubtractedImageDirectoryLabel = uilabel(app.ParticleDataCTDTab);
            app.SubtractedImageDirectoryLabel.Text = 'Subtracted Image Directory:';
            app.GrayscaleThresholdEditFieldLabel = uilabel(app.ParticleDataCTDTab);
            app.GrayscaleThresholdEditFieldLabel.Text = 'Grayscale Threshold: ';
            app.GrayscaleThresholdEditField = uieditfield(app.ParticleDataCTDTab, 'numeric');
            app.GrayscaleThresholdEditField.Limits = [15 100];
            app.GrayscaleThresholdEditField.ValueDisplayFormat = '%.0f';
            app.GrayscaleThresholdEditField.Value = 29;
            app.chooseButton_5 = uibutton(app.ParticleDataCTDTab, 'push');
            app.chooseButton_5.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_5Pushed, true);
            app.chooseButton_5.Text = 'choose';
            app.pathdisp_5 = uilabel(app.ParticleDataCTDTab);
            app.pathdisp_5.Text = '';
            app.CTDfileLabel = uilabel(app.ParticleDataCTDTab);
            app.CTDfileLabel.Text = 'CTD file:';
            app.valuerangesfrom0255Label = uilabel(app.ParticleDataCTDTab);
            app.valuerangesfrom0255Label.Text = {'Threshold ranges from 0-255. Generally, a lower threshold detects more particles but might introduce noises, '; 'whereas a higher threshold captures denser particles. The threshold=29 by default is tested as a solid threshold'; 'to analyze dense particles.'};
            app.valuerangesfrom0255Label_2 = uilabel(app.ParticleDataCTDTab);
            app.valuerangesfrom0255Label_2.Text = '(0-255)';
            app.valuerangesfrom0255Label_3 = uilabel(app.ParticleDataCTDTab);
            app.valuerangesfrom0255Label_3.Text = {'There are three steps in this program:'; ''; 'Step 1 extracts the particle information (e.g., area, diameter, mean intensity) from images and save '; 'them into a csv file for further analysis.'; ''; 'Step 2 organizing CTD files for further analysis in step 3. If the input is a csv file, then the station No., '; 'latitude and longitude columns required to be put it manually, otherwise they will not show in the '; 'generated files. '; ''; 'Step 3 aligns the output from previous process to the CTD data based on time points and julian days.'; ''};
            app.Label_14 = uilabel(app.ParticleDataCTDTab);
            app.Label_14.Text = 'Copy the output directory from "Image Subtraction" Tab.';
            app.Label_15 = uilabel(app.ParticleDataCTDTab);
            app.Label_15.Text = 'This program works for cnv files generated by SBE, also csv files.';
            app.StationNoEditFieldLabel = uilabel(app.ParticleDataCTDTab);
            app.StationNoEditFieldLabel.Text = 'Station No.:';
            app.StationNoEditField = uieditfield(app.ParticleDataCTDTab, 'numeric');
            app.StationNoEditField.Limits = [0 1000000];
            app.VerticalProfilesTab = uitab(app.TabGroup);
            app.VerticalProfilesTab.Title = 'Vertical Profiles';
            app.Panel_5 = uipanel(app.VerticalProfilesTab);
            app.Panel_5.Title = '   ';
            app.Hyperlink_5 = uihyperlink(app.Panel_5);
            app.Hyperlink_5.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_5Clicked, true);
            app.Hyperlink_5.Text = 'click here to find the saved files';
            app.Panel_4 = uipanel(app.VerticalProfilesTab);
            app.Panel_4.Title = '  ';
            app.chooseButton_8 = uibutton(app.Panel_4, 'push');
            app.chooseButton_8.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_8Pushed, true);
            app.chooseButton_8.Text = 'choose';
            app.CTDFactorsLabel = uilabel(app.Panel_4);
            app.CTDFactorsLabel.Text = 'CTD Factors:';
            app.dep_min = uieditfield(app.Panel_4, 'numeric');
            app.dep_min.Limits = [0 5000];
            app.dep_min.AllowEmpty = 'on';
            app.dep_min.Value = [];
            app.dep_max = uieditfield(app.Panel_4, 'numeric');
            app.dep_max.Limits = [0 10000];
            app.dep_max.AllowEmpty = 'on';
            app.dep_max.Value = [];
            app.FoSITIMEwihcalibrationclockEditFieldLabel_2 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_2.Text = 'Depth Range:';
            app.FoSITIMEwihcalibrationclockEditFieldLabel_3 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_3.Text = 'to';
            app.FoSITIMEwihcalibrationclockEditFieldLabel_4 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_4.Text = 'm';
            app.pathdisp_10 = uilabel(app.Panel_4);
            app.pathdisp_10.Text = '';
            app.CombinedFileLabel = uilabel(app.Panel_4);
            app.CombinedFileLabel.Text = 'Combined File:';
            app.chooseButton_10 = uibutton(app.Panel_4, 'push');
            app.chooseButton_10.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_10Pushed, true);
            app.chooseButton_10.Text = 'choose';
            app.pathdisp_11 = uilabel(app.Panel_4);
            app.pathdisp_11.Text = '';
            app.CTDfileLabel_2 = uilabel(app.Panel_4);
            app.CTDfileLabel_2.Text = 'CTD file:';
            app.Label_16 = uilabel(app.Panel_4);
            app.Label_16.Text = 'CTD file has to be in csv format.';
            app.partsize_min = uieditfield(app.Panel_4, 'numeric');
            app.partsize_min.Limits = [20 5000];
            app.partsize_min.AllowEmpty = 'on';
            app.partsize_min.Value = [];
            app.partsize_max = uieditfield(app.Panel_4, 'numeric');
            app.partsize_max.Limits = [20 5000];
            app.partsize_max.AllowEmpty = 'on';
            app.partsize_max.Value = [];
            app.FoSITIMEwihcalibrationclockEditFieldLabel_6 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_6.Text = 'Particle Size Range:';
            app.FoSITIMEwihcalibrationclockEditFieldLabel_7 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_7.Text = 'to';
            app.FoSITIMEwihcalibrationclockEditFieldLabel_8 = uilabel(app.Panel_4);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_8.Text = ' µm';
            app.Label_17 = uilabel(app.Panel_4);
            app.Label_17.Text = 'The ranges can be blank if analyzing the whole dataset';
            app.Label_18 = uilabel(app.Panel_4);
            app.Label_18.Text = 'Please verify that the column of depth is titled with the keyword "Depth" (sometimes people use "pressure")';
            app.RUNButton = uibutton(app.VerticalProfilesTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed2, true);
            app.RUNButton.Tag = '4564';
            app.RUNButton.Text = 'RUN!(>''-'')>';
            app.chooseButton_9 = uibutton(app.VerticalProfilesTab, 'push');
            app.chooseButton_9.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_9Pushed, true);
            app.chooseButton_9.Text = 'choose';
            app.pathdisp_9 = uilabel(app.VerticalProfilesTab);
            app.pathdisp_9.Text = '';
            app.OutputDirLabel = uilabel(app.VerticalProfilesTab);
            app.OutputDirLabel.Text = 'Output Dir:';
            app.PixelResolutionEditField_2Label = uilabel(app.VerticalProfilesTab);
            app.PixelResolutionEditField_2Label.Text = 'Pixel Resolution:';
            app.PixelResolutionEditField_2 = uieditfield(app.VerticalProfilesTab, 'numeric');
            app.PixelResolutionEditField_2.Limits = [0 1000];
            app.PixelResolutionEditField_2.Value = 12;
            app.mpixelLabel = uilabel(app.VerticalProfilesTab);
            app.mpixelLabel.Text = ' µm/pixel';
            app.FoSITIMEwihcalibrationclockEditFieldLabel_9 = uilabel(app.VerticalProfilesTab);
            app.FoSITIMEwihcalibrationclockEditFieldLabel_9.Text = 'cm';
            app.DepthoffieldEditField = uieditfield(app.VerticalProfilesTab, 'numeric');
            app.DepthoffieldEditField.Limits = [0 100];
            app.DepthoffieldEditField.Value = 3;
            app.DepthoffieldEditField_3Label = uilabel(app.VerticalProfilesTab);
            app.DepthoffieldEditField_3Label.Text = 'Depth of field:';
            app.VerticalProfilePlottingLabel = uilabel(app.VerticalProfilesTab);
            app.VerticalProfilePlottingLabel.Text = 'Vertical Profile Plotting';
            app.valuerangesfrom0255Label_4 = uilabel(app.VerticalProfilesTab);
            app.valuerangesfrom0255Label_4.Text = {'  Two files will be generated in this process. One with the raw data output (missing particle data filled with '; 'NaN) and another with interpolation.'; '  For the sake of vertical plot of particle distribution, the interpolation is necessary to fill the gaps.'};
            app.valuerangesfrom0255Label_5 = uilabel(app.VerticalProfilesTab);
            app.valuerangesfrom0255Label_5.Text = {' The window size has to be an odd number. The plot figure will'; ' be automatically saved under the same directory.'};
            app.SmoothWindowLabel = uilabel(app.VerticalProfilesTab);
            app.SmoothWindowLabel.Text = 'Smooth Window: ';
            app.SmoothWindowEditField = uieditfield(app.VerticalProfilesTab, 'numeric');
            app.SmoothWindowEditField.Limits = [0 200];
            app.SmoothWindowEditField.ValueDisplayFormat = '%.0f';
            app.SmoothWindowEditField.Value = 11;
            app.PLOTinNewWindowButton = uibutton(app.VerticalProfilesTab, 'push');
            app.PLOTinNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTinNewWindowButtonPushed, true);
            app.PLOTinNewWindowButton.Text = 'PLOT (in New Window)';
            app.valuerangesfrom0255Label_6 = uilabel(app.VerticalProfilesTab);
            app.valuerangesfrom0255Label_6.Text = '  Output saved to the save directory as combined file if blank, otherwise please specify.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FoSI_sync_multi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end